classdef morse_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IMPORTButton             matlab.ui.control.Button
        DECODEEditField          matlab.ui.control.EditField
        DECODEEditFieldLabel     matlab.ui.control.Label
        PlayButton               matlab.ui.control.Button
        ENCODEEditField          matlab.ui.control.EditField
        ENCODEEditFieldLabel     matlab.ui.control.Label
        PLOTButton               matlab.ui.control.Button
        INPUTTEXTEditField       matlab.ui.control.EditField
        INPUTTEXTEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [s,y] = morse_encode(app,x,fs)
            % y will have audio amplitudes and s will have the morse code.
            % fs for sampling frequency
            %y_matrix is the matrix form before flatening for sound vector

            % table of characters
            d = { ...
                ' ',' ';
                'A','.-';
                'B','-...';
                'C','-.-.';
                'D','-..';
                'E','.';
                'F','..-.';
                'G','--.';
                'H','....';
                'I','..';
                'J','.---';
                'K','-.-';
                'L','.-..';
                'M','--';
                'N','-.';
                'O','---';
                'P','.--.';
                'Q','--.-';
                'R','.-.';
                'S','...';
                'T','-';
                'U','..-';
                'V','...-';
                'W','.--';
                'X','-..-';
                'Y','-.--';
                'Z','--..';
                '0','-----';
                '1','.---';
                '2','..---';
                '3','...--';
                '4','....-';
                '5','.....';
                '6','-....';
                '7','--...';
                '8','---..';
                '9','----.';
                '.','.-.-.-';
                ',','--..--';
                '?','..--..';
                '''','.----.';
                '!','-.-.--';
                };

            % sound constants
            tone = 800; % tone frequency (Hz)
            unit = 0.1; % unit duration (s)
            %fs = 8000; % sampling rate (Hz), must be multiple of tone to avoid gliches

            % converts text string in Morse code cell array
            [~,kb] = ismember(upper(x(:)),d(:,1)); %finding the row numbers of the matched characters from cell array

            % any unknown character replaced by space
            kb(kb<1) = 1;

            % makes Morse code string
            s = strjoin(d(kb,2),' '); %1 extra space is getting added between each morse symbol

            b = strrep(s,'.','10'); % dot is 1 unit length + 1 unit space
            b = strrep(b,'-','1110'); % dash is 3 units length + 1 unit space
            b = strrep(b,'   ','0000000'); % space between words is 7 units
            b = strrep(b,' ','00'); % space beween letters is 3 units (1 already added)
            w = logical(str2num(b(:))');

            y = bin2wav(app,w,fs,tone,unit);
  
        end
        
        function [y] = bin2wav(app,x,fs,tone,unit)
            n = round(unit*fs); % number of samples in a unit
            m = length(x); % number of units
            y = zeros(n,m); % makes a matrix of flat signal
            s = linspace(0,2*pi*tone*unit,n+1)'; %generating time vector for one full cycle including 0
            y(:,x) = repmat(sin(s(1:end-1)),1,sum(x)); %inserting sound only for 1 and silience for 0. Repeating column wise
            %y_matrix=y;
            y = y(:); %flattening the sound vector
        end
        
        function morse=decode_morse_audio(app,file)
            [data,Fs]=audioread(file);
            %segmenting
            %output is in segments variable
            %each row is a one sound here

            segment_duration=0.1; %seconds
            len_segment = fix((Fs * segment_duration)); %there was a +1
            num_segments = ceil(length(data) / len_segment);

            % Initializing segments matrix
            segments = zeros(num_segments, len_segment);

            for i = 1:num_segments
                % Determine the start and end indices for the current segment
                start_idx = (i - 1) * len_segment + 1;
                end_idx = min(start_idx + len_segment - 1, length(data));

                segment = data(start_idx:end_idx);

                if length(segment) < len_segment
                    segment = [segment, zeros(1, len_segment - length(segment))]; %zero padding if needed
                end

                segments(i, :) = segment;
            end

            %clearing unncessary varibales
            clearvars -except segments fs Fs decoded data

            %converting each row to sequence of 1 if there was sound and 0 if no sound
            threshold=5 ; %no of non zero elements that can be present in one row to make it still a silence
            [row,column]=size(segments);

            for i=1:row
                count=0 ; %counting non zero elelemt in one row
                for j=1:column
                    if segments(i,j)~=0
                        count=count+1;
                    end
                end
                if count>threshold
                    segments(i,:)=ones(1,column);
                else
                    segments(i,:)=zeros(1,column);
                end
            end
            %transforming the segments matrix into morse code symbols
            i = 1;
            morse = '';
            [row, column] = size(segments);

            while i <= row

                % Check for dot condition
                if (i+1 <= row) && (sum(segments(i, :)) > 0) && (sum(segments(i+1, :)) == 0)
                    morse = [morse, '.'];
                    i = i + 2;
                    continue;
                end

                % Check for dash condition
                if (i+3 <= row) && (sum(segments(i, :)) > 0) && (sum(segments(i+1, :)) > 0) && (sum(segments(i+2, :)) > 0) && (sum(segments(i+3, :)) == 0)
                    morse = [morse, '-'];
                    i = i + 4;
                    continue;
                end

                % Check for word space (7 consecutive rows of 0s)
                if (i+6 <= row) && all(sum(segments(i:i+6, :)) == 0)
                    morse = [morse, '   '];
                    i = i + 7;
                    continue;
                end

                % Check for letter space (2 consecutive rows of 0s)
                if (i+1 <= row) && all(sum(segments(i:i+1, :)) == 0)
                    morse = [morse, ' '];
                    i = i + 2;
                    continue;
                end

                % If none of the conditions match, just move to the next row
                i = i + 1;
            end
            
        end
        
        function s=morse_decode(app,x)
            d = { ...
                ' ',' ';
                'A','.-';
                'B','-...';
                'C','-.-.';
                'D','-..';
                'E','.';
                'F','..-.';
                'G','--.';
                'H','....';
                'I','..';
                'J','.---';
                'K','-.-';
                'L','.-..';
                'M','--';
                'N','-.';
                'O','---';
                'P','.--.';
                'Q','--.-';
                'R','.-.';
                'S','...';
                'T','-';
                'U','..-';
                'V','...-';
                'W','.--';
                'X','-..-';
                'Y','-.--';
                'Z','--..';
                '0','-----';
                '1','.---';
                '2','..---';
                '3','...--';
                '4','....-';
                '5','.....';
                '6','-....';
                '7','--...';
                '8','---..';
                '9','----.';
                '.','.-.-.-';
                ',','--..--';
                '?','..--..';
                '''','.----.';
                '!','-.-.--';
                };
            xx = strsplit(x,'\s\s+','DelimiterType','regularexpression'); % split the words
            s = cell(1,length(xx));

            for n = 1:length(xx)
                [ka,kb] = ismember(strsplit(xx{n},' '),d(:,2)); % search for letters
                kb(kb<1) = []; % unknown sequences are ignored
                ss = repmat(' ',1,length(ka));
                ss(ka) = char(d{kb,1});
                s{n} = ss;
            end
            s = strjoin(s); % appends the words
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: INPUTTEXTEditField, INPUTTEXTEditField, 
        % ...and 1 other component
        function PLOTButtonPushed(app, event)
            str = app.INPUTTEXTEditField.Value;
            fs=8000;
            [s,y]=morse_encode(app,str,fs);
            data=y;
            t=(0:length(data)-1)/fs;
            plot(app.UIAxes,data);
            app.ENCODEEditField.Value = s;
            

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            str = app.INPUTTEXTEditField.Value;
            fs=8000;
            [~,y]=morse_encode(app,str,fs);
            soundsc(y,fs);
            audiowrite('test.wav',y,fs)
        end

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)
            [file,~] = uigetfile('*.wav');
            morse = decode_morse_audio(app,file);
            app.DECODEEditField.Value = morse_decode(app,morse);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MORSE CODE')
            xlabel(app.UIAxes, 'Time(seconds)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.INPUTTEXTEditFieldLabel = uilabel(app.UIFigure);
            app.INPUTTEXTEditFieldLabel.Text = 'INPUT TEXT ';
            app.INPUTTEXTEditField = uieditfield(app.UIFigure, 'text');
            app.INPUTTEXTEditField.ValueChangedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.INPUTTEXTEditField.ValueChangingFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.ENCODEEditFieldLabel = uilabel(app.UIFigure);
            app.ENCODEEditFieldLabel.Text = 'ENCODE';
            app.ENCODEEditField = uieditfield(app.UIFigure, 'text');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.DECODEEditFieldLabel = uilabel(app.UIFigure);
            app.DECODEEditFieldLabel.Text = 'DECODE';
            app.DECODEEditField = uieditfield(app.UIFigure, 'text');
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morse_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end